classdef ReliaCompGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        Menu_2                          matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        DocumentationMenu               matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        FunctionDefinitionPanel         matlab.ui.container.Panel
        NumberofVariablesSpinnerLabel   matlab.ui.control.Label
        NumberofVariablesSpinner        matlab.ui.control.Spinner
        FuncInputField                  matlab.ui.control.EditField
        SelectFunctionButton            matlab.ui.control.Button
        ComputationMethodDropDownLabel  matlab.ui.control.Label
        ComputationMethodDropDown       matlab.ui.control.DropDown
        GradPertubationEditFieldLabel   matlab.ui.control.Label
        GradPertubationEditField        matlab.ui.control.NumericEditField
        GradientMethodDropDownLabel     matlab.ui.control.Label
        GradientMethodDropDown          matlab.ui.control.DropDown
        ConvergenceAlphaEditFieldLabel  matlab.ui.control.Label
        ConvergenceAlphaEditField       matlab.ui.control.NumericEditField
        ConvergenceBetaEditFieldLabel   matlab.ui.control.Label
        ConvergenceBetaEditField        matlab.ui.control.NumericEditField
        RUNButton                       matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        InputsTab                       matlab.ui.container.Tab
        InputTable                      matlab.ui.control.Table
        CorrTable                       matlab.ui.control.Table
        OutputTab                       matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        ResultsTab                      matlab.ui.container.Tab
        ResultsTable                    matlab.ui.control.Table
    end

    
    properties (Access = public)
        DataStruct = struct('input',[],'model',[]); % Description
    end
    
    methods (Access = public)
        
        function UpdateOutputWindow(app,nextLine,type)
            if type == 1
                DispString = sprintf('[%s] \t %s',datestr(now,'ddmmmyyyy HH:MM:SS'),nextLine);
                app.TextArea.Value = [DispString;app.TextArea.Value];
                pause(0.01)
            else
                app.TextArea.Value{1} = sprintf('[%s] \t %s',datestr(now,'ddmmmyyyy HH:MM:SS'),nextLine);
            end
        end
        
        function Switch2InputTab(app)
            app.TabGroup.SelectedTab = app.InputsTab;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InputTable.RowName = 'numbered';
            app.CorrTable.RowName = 'numbered';
            clc
            addpath('utils')
        end

        % Button pushed function: SelectFunctionButton
        function SelectFunctionButtonPushed(app, event)
            [file,path] = uigetfile('*.m');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                app.FuncInputField.Value = file;
                app.DataStruct.input.Func = file;
                app.DataStruct.input.Path = path;
            end
            
            app.Switch2InputTab
        end

        % Value changed function: NumberofVariablesSpinner
        function NumberofVariablesSpinnerValueChanged(app, event)
            value = app.NumberofVariablesSpinner.Value;
            
            cnames = categorical({'Normal'},{'Normal','Log Normal','Uniform'},'Ordinal',true);
            TableTemplate = table(" ",0,0,cnames,'VariableNames',{'VarName'; 'Mean'; 'COV'; 'Dist'});
            
            if value > 0
                
                app.InputTable.ColumnEditable = true;
                app.CorrTable.ColumnEditable = true;
                
                covTableVals = app.CorrTable.Data;
                
                if size(app.InputTable.Data,1) < value % The number is being increased
                    app.InputTable.Data = [app.InputTable.Data; TableTemplate]; % Setting up for Data input table
                    
                    if value == 1
                        app.CorrTable.Data = [1];
                        app.CorrTable.ColumnName = 1;
                    else
                        app.CorrTable.Data = [covTableVals zeros(size(covTableVals,1),1);zeros(1,value-1) 1];
                        app.CorrTable.ColumnName = 1:value;
                    end
                    
                    app.CorrTable.ColumnWidth = num2cell((app.CorrTable.InnerPosition(3) ./ (value+1)).* ones(1,value));
                    
                else % The number is being decreased or same
                    app.InputTable.Data = app.InputTable.Data(1:value,:);
                    app.CorrTable.Data = app.CorrTable.Data(1:value,1:value);
                end
                
            else % Has a value of Zero!
                app.InputTable.Data =[];
                app.CorrTable.Data =[];
                
            end
            
            app.DataStruct.inputs.NoOfVars = value;
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            % Here is where the fun starts!!!!! 
            UpdateOutputWindow(app,'---------------------------------------',0)    
            
            % Switching over to the output tab!
            app.TabGroup.SelectedTab = app.OutputTab;
            
            % Getting the information from all the inputs and storing it in
            % the public property structure!
            
            UpdateOutputWindow(app,'Checking input function....',1)
            
            if isempty(app.DataStruct.input)
                UpdateOutputWindow(app,'No input function Found! please select input function',1)
                % Alerting user and switching over to the Input tab!
                uialert(app.UIFigure,'Input function not found, please select input function','Error')
                return
            else
                UpdateOutputWindow(app,'Checking input function.... Done',0)
            end
            
            UpdateOutputWindow(app,'Adding path......',1)
            addpath(app.DataStruct.input.Path)
            UpdateOutputWindow(app,'Adding path...... Done',0)
            model.funcName = app.DataStruct.input.Func(1:end-2);
            
            
            UpdateOutputWindow(app,'Checking input data....',1)
            if app.NumberofVariablesSpinner.Value == 0
                UpdateOutputWindow(app,'No input data provided!',1)
                % Alerting user and switching over to the Input tab!
                uialert(app.UIFigure,'Input variables not defined, please define input variables','Error')
                return
            else
                UpdateOutputWindow(app,sprintf('Checking input data.... %d variables defined!',app.DataStruct.inputs.NoOfVars),0)
            end
            
            % Read the data and populate the model structure
            UpdateOutputWindow(app,'Reading in tabular data....',1)
            
            if any(isnan(table2array(app.InputTable.Data(:,[2,3]))),'all') ||...
                    any(isnan(app.CorrTable.Data),'all')
                UpdateOutputWindow(app,'NaN found in the tabular input data!',1)
                % Alerting user and switching over to the Input tab!
                uialert(app.UIFigure,'NaN found in tabular input data please correct and re-run','Error')
                return
            else
                model.varsMeans = app.InputTable.Data.Mean;
                model.varsCOV = app.InputTable.Data.COV;
                
                model.varSD = model.varsMeans.*model.varsCOV; % Calc of SD's
                
                DistMap = {'Normal','Log Normal','Uniform'};
                for idx = 1:app.NumberofVariablesSpinner.Value
                    model.Types(idx) = find(strcmp(string(app.InputTable.Data.Dist(idx)),DistMap)) - 1;
                end
                
                model.Cprime = app.CorrTable.Data;
                
                model.LNSD = sqrt(log(1 + model.varsCOV.^2));
                model.LNmean = log(model.varsMeans) - 0.5 .* model.LNSD .^2;
                
                UpdateOutputWindow(app,'Reading in tabular data.... Done!',0)
            end
            
            % Reading in the specs of the iterations!
            
            model.gradFlag = strcmp(app.GradientMethodDropDown,'Central Diff');
            model.gradDelta = app.GradPertubationEditField.Value; % numerical gradient perturbation in %
            model.BetaDiff = app.ConvergenceBetaEditField.Value; % Convergence criteria for Beta
            model.AlphaDiff = app.ConvergenceBetaEditField.Value; % Convergence criteria for Beta


            % Test the input function for the right number of input
            % variables!
            UpdateOutputWindow(app,'Testing input function for validity....',1)
            
            try
                feval(model.funcName,model.varsMeans)
                UpdateOutputWindow(app,'Testing input function for validity.... Done!',0)
            catch ME
                UpdateOutputWindow(app,'Testing input function for validity.... Failed!',0)
                switch ME.identifier
                    
                    case 'MATLAB:UndefinedFunction'
                        uialert(app.UIFigure,'The input function is undefined!','Error')
                        return
                        
                    case 'MATLAB:badsubscript'
                        uialert(app.UIFigure,'Missmatch in number of variable provided and number of inputs taken in by function.','Error')
                        return
                        
                    otherwise
                        uialert(app.UIFigure,ME.message,'MATLAB Error')
                        return
                end
            end
            
            app.DataStruct.model = model;
            
            % Run the HL-RF Reliability analysis !!!!!
            rel = findReliability(app);
            
            % Changing the tab to the Output......!!!!
            app.TabGroup.SelectedTab = app.ResultsTab;
            tempTable = struct2table(rel);
            ResTable = table(tempTable.beta,tempTable.R .* 100,tempTable.Pf .* 100);
            app.ResultsTable.Data = ResTable;
            app.ResultsTable.ColumnName = {'Beta'; 'Reliability'; 'Probability of Failure'};
            
            % Can be done in a better way, this is a temp fix! 
            app.ResultsTable.ColumnName(end+1:end+app.NumberofVariablesSpinner.Value) =...
                                                            table2cell(app.InputTable.Data(:,1));
            tabLen = length(app.ResultsTable.ColumnName);
            for jdx = 1:length(rel)
                app.ResultsTable.Data(jdx,tabLen-app.NumberofVariablesSpinner.Value+1:tabLen) = num2cell(tempTable.xStar{jdx})';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.Text = 'Documentation';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.FunctionDefinitionPanel = uipanel(app.UIFigure);
            app.FunctionDefinitionPanel.Title = 'Function Definition';
            app.NumberofVariablesSpinnerLabel = uilabel(app.FunctionDefinitionPanel);
            app.NumberofVariablesSpinnerLabel.Text = 'Number of Variables';
            app.NumberofVariablesSpinner = uispinner(app.FunctionDefinitionPanel);
            app.NumberofVariablesSpinner.Limits = [0 Inf];
            app.NumberofVariablesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofVariablesSpinnerValueChanged, true);
            app.FuncInputField = uieditfield(app.FunctionDefinitionPanel, 'text');
            app.SelectFunctionButton = uibutton(app.FunctionDefinitionPanel, 'push');
            app.SelectFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFunctionButtonPushed, true);
            app.SelectFunctionButton.Text = 'Select Function';
            app.ComputationMethodDropDownLabel = uilabel(app.FunctionDefinitionPanel);
            app.ComputationMethodDropDownLabel.Text = 'Computation Method';
            app.ComputationMethodDropDown = uidropdown(app.FunctionDefinitionPanel);
            app.ComputationMethodDropDown.Items = {'HL-RF', 'Monte Carlo', 'MVFSOM'};
            app.ComputationMethodDropDown.Value = 'Monte Carlo';
            app.GradPertubationEditFieldLabel = uilabel(app.FunctionDefinitionPanel);
            app.GradPertubationEditFieldLabel.Text = 'Grad Pertubation';
            app.GradPertubationEditField = uieditfield(app.FunctionDefinitionPanel, 'numeric');
            app.GradPertubationEditField.Value = 1e-06;
            app.GradientMethodDropDownLabel = uilabel(app.FunctionDefinitionPanel);
            app.GradientMethodDropDownLabel.Text = 'Gradient Method';
            app.GradientMethodDropDown = uidropdown(app.FunctionDefinitionPanel);
            app.GradientMethodDropDown.Items = {'Central Diff', 'User Provided'};
            app.GradientMethodDropDown.Value = 'Central Diff';
            app.ConvergenceAlphaEditFieldLabel = uilabel(app.FunctionDefinitionPanel);
            app.ConvergenceAlphaEditFieldLabel.Text = 'Convergence Alpha';
            app.ConvergenceAlphaEditField = uieditfield(app.FunctionDefinitionPanel, 'numeric');
            app.ConvergenceAlphaEditField.Value = 1e-06;
            app.ConvergenceBetaEditFieldLabel = uilabel(app.FunctionDefinitionPanel);
            app.ConvergenceBetaEditFieldLabel.Text = 'Convergence Beta';
            app.ConvergenceBetaEditField = uieditfield(app.FunctionDefinitionPanel, 'numeric');
            app.ConvergenceBetaEditField.Value = 1e-09;
            app.RUNButton = uibutton(app.FunctionDefinitionPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN!';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Variable Inputs';
            app.InputTable = uitable(app.InputsTab);
            app.InputTable.ColumnName = {'Variable Name'; 'Mean'; 'COV'; 'Distributions'};
            app.InputTable.RowName = {''};
            app.InputTable.ColumnSortable = false;
            app.InputTable.ColumnEditable = true;
            app.CorrTable = uitable(app.InputsTab);
            app.CorrTable.ColumnName = {''};
            app.CorrTable.RowName = {''};
            app.CorrTable.ColumnSortable = false;
            app.CorrTable.ColumnEditable = true;
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.TextArea = uitextarea(app.OutputTab);
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ResultsTable = uitable(app.ResultsTab);
            app.ResultsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ResultsTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReliaCompGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end